classdef MomentumEquationsAstroApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        SaveplotMenu                matlab.ui.container.Menu
        ExportToWorkspaceMenu       matlab.ui.container.Menu
        ExportConditionsMenu        matlab.ui.container.Menu
        ExportDataMenu              matlab.ui.container.Menu
        AngleCalculatorMenu         matlab.ui.container.Menu
        HelpMenu_2                  matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        DocumentationMenu           matlab.ui.container.Menu
        MOMENTUMEQUATIONSINASTROPHYSICSLabel  matlab.ui.control.Label
        ResetFigureButton           matlab.ui.control.Button
        HoldonCheckBox              matlab.ui.control.CheckBox
        CommonplanetsDropDown       matlab.ui.control.DropDown
        CommonplanetsDropDownLabel  matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        OmegaEditField              matlab.ui.control.NumericEditField
        OmegaEditFieldLabel         matlab.ui.control.Label
        phiEditField                matlab.ui.control.NumericEditField
        phiEditFieldLabel           matlab.ui.control.Label
        aEditField                  matlab.ui.control.NumericEditField
        aEditFieldLabel             matlab.ui.control.Label
        tEditField                  matlab.ui.control.NumericEditField
        tEditFieldLabel             matlab.ui.control.Label
        RepresenttrajectoryButton   matlab.ui.control.Button
        z0EditField                 matlab.ui.control.NumericEditField
        z0EditFieldLabel            matlab.ui.control.Label
        y0EditField                 matlab.ui.control.NumericEditField
        y0EditFieldLabel            matlab.ui.control.Label
        x0EditField                 matlab.ui.control.NumericEditField
        x0EditFieldLabel            matlab.ui.control.Label
        w0EditField                 matlab.ui.control.NumericEditField
        w0EditFieldLabel            matlab.ui.control.Label
        v0EditField                 matlab.ui.control.NumericEditField
        v0EditFieldLabel            matlab.ui.control.Label
        u0EditField                 matlab.ui.control.NumericEditField
        u0EditFieldLabel            matlab.ui.control.Label
        InitialConditionsinISUnitsPanel  matlab.ui.container.Panel
        SimulationTimeinsPanel      matlab.ui.container.Panel
        PlanetRadiusinmPanel        matlab.ui.container.Panel
        LatitudeinradPanel          matlab.ui.container.Panel
        PlanetsAngularVelocityinradsPanel  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        ContextMenu2                matlab.ui.container.ContextMenu
        SaveasMenu                  matlab.ui.container.Menu
        ExporttoWorkspaceMenu       matlab.ui.container.Menu
        RenamecurveMenu             matlab.ui.container.Menu
        HidecurveMenu               matlab.ui.container.Menu
        ShowcurveMenu               matlab.ui.container.Menu
        ContextMenu3                matlab.ui.container.ContextMenu
        OpenHelpMenu                matlab.ui.container.Menu
        OpenDocumentationMenu       matlab.ui.container.Menu
        ExitMenu                    matlab.ui.container.Menu
        ContextMenu4                matlab.ui.container.ContextMenu
        ShowDegreesRadiansCalculatorMenu  matlab.ui.container.Menu
    end

    
    properties (Access = private)
        HelpAppInstance
        DocumentationAppInstance
        PlanetCounter = 0;
        RepresentedCurves
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.OmegaEditField.Value = 1.24e-6;
            app.aEditField.Value = 2439700;
            app.RepresentedCurves = {};
        end

        % Button pushed function: RepresenttrajectoryButton
        function RepresenttrajectoryButtonPushed(app, event)
% Generate a unique identifier for the trajectory based on all parameters
curveIdentifier = sprintf(...
    'a=%.2f, Omega=%.9f, u0=%.2f, v0=%.2f, w0=%.2f, x0=%.2f, y0=%.2f, z0=%.2f, t=%.2f, phi=%.2f', ...
    app.aEditField.Value, ...
    app.OmegaEditField.Value, ...
    app.u0EditField.Value, ...
    app.v0EditField.Value, ...
    app.w0EditField.Value, ...
    app.x0EditField.Value, ...
    app.y0EditField.Value, ...
    app.z0EditField.Value, ...
    app.tEditField.Value, ...
    app.phiEditField.Value);

% Check if the trajectory has already been represented
if ismember(curveIdentifier, app.RepresentedCurves)
    uialert(app.UIFigure, 'This trajectory has already been represented.', 'Duplicate Trajectory');
    return; % Exit the function to prevent duplicate plotting
end

% If not already represented, add it to the list
app.RepresentedCurves{end+1} = curveIdentifier;


% Get the selected planet and its properties
selectedPlanet = app.CommonplanetsDropDown.Value;

% Known planet properties
planetData = struct(...
    'Earth', struct('a', 6371000, 'Omega', 7.2921e-5), ...
    'Venus', struct('a', 6051800, 'Omega', -2.99e-7), ...
    'Mars', struct('a', 3389500, 'Omega', 7.0882e-5), ...
    'Jupiter', struct('a', 69911000, 'Omega', 1.76e-4), ...
    'Saturn', struct('a', 58232000, 'Omega', 1.64e-4), ...
    'Mercury', struct('a', 2439700, 'Omega', 1.24e-6), ...
    'Uranus', struct('a', 25362000, 'Omega', -1.01e-4), ...
    'Neptune', struct('a', 24622000, 'Omega', 1.08e-4));

% Current properties
current_a = app.aEditField.Value;
current_Omega = app.OmegaEditField.Value;

% Check if current values match a known planet
isKnownPlanet = isfield(planetData, selectedPlanet) && ...
                abs(current_a - planetData.(selectedPlanet).a) < 1e-2 && ...
                abs(current_Omega - planetData.(selectedPlanet).Omega) < 1e-9;

% Determine label for the curve
if isKnownPlanet
    label = selectedPlanet;
else
    % Increment counter for unnamed planets
    app.PlanetCounter = app.PlanetCounter + 1;
    label = ['Planet ', num2str(app.PlanetCounter)];
end

% Constants
Omega = app.OmegaEditField.Value; % Earth's angular velocity
phi = app.phiEditField.Value;        % Latitude
a = app.aEditField.Value;        % Earth's radius
t_final = app.tEditField.Value;    % Simulation time

% Initial conditions
u0 = app.u0EditField.Value;           % Initial zonal velocity (m/s)
v0 = app.v0EditField.Value;            % Initial meridional velocity (m/s)
w0 = app.w0EditField.Value;            % Initial vertical velocity (m/s)
x0 = app.x0EditField.Value;            % Initial x position (m)
y0 = app.y0EditField.Value;            % Initial y position (m)
z0 = app.z0EditField.Value;            % Initial z position (m)
initial_conditions = [u0, v0, w0, x0, y0, z0];

% Define the system of equations as a function
ode_system = @(t, y) [
    2*Omega*y(2)*sin(phi) - 2*Omega*y(3)*cos(phi) - (y(1)*y(3))/a + (y(1)*y(2)/a)*tan(phi); % du/dt
    -2*Omega*y(1)*sin(phi) - (y(1)^2/a)*tan(phi);                                           % dv/dt
    2*Omega*y(1)*cos(phi) + (y(1)^2/a);                                                    % dw/dt
    y(1); % dx/dt = u
    y(2); % dy/dt = v
    y(3); % dz/dt = w
];

% Solve the system of ODEs
[t, y] = ode45(ode_system, [0, t_final], initial_conditions);

% Extract results
u = y(:, 1);
v = y(:, 2);
w = y(:, 3);
x = y(:, 4);
y_pos = y(:, 5); % Renaming to avoid conflict with the solution matrix y
z = y(:, 6);

% Verificar que el valor de a (radio) es positivo
if app.aEditField.Value <= 0
    uialert(app.UIFigure, 'The radius (a) must be a positive value.', 'Invalid Input');
    return; % Salir si el valor de a no es válido
elseif app.tEditField.Value <= 0
    uialert(app.UIFigure, 'The time (t) must be a positive value.', 'Invalid Input');
    return; % Salir si el valor de t no es válido
elseif phi < -pi/2 || phi > pi/2
    uialert(app.UIFigure, 'The latitude (phi) must be between -π/2 and π/2 radians.', 'Invalid Input');
    return; % Salir si el valor de phi no es válido
end

% 3D plot of the trajectory
plot3(app.UIAxes,x, y_pos, z, 'LineWidth', 1.5, 'DisplayName', label);
view(app.UIAxes, 3); % Set 3D view

% Update legend
legend(app.UIAxes, 'show', 'Location', 'best')
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
    run('HelpApp.mlapp')
        end

        % Menu selected function: DocumentationMenu
        function DocumentationMenuSelected(app, event)
    run('DocumentationApp.mlapp')
        end

        % Value changed function: CommonplanetsDropDown
        function CommonplanetsDropDownValueChanged(app, event)
    selectedOption = app.CommonplanetsDropDown.Value; % Get the selected option
    
    % Set default values based on the selected option
    switch selectedOption
        case 'Earth'
            app.aEditField.Value = 6371000;       % Default Earth radius (m)
            app.OmegaEditField.Value = 7.2921e-5; % Default angular velocity of Earth (rad/s)
            
        case 'Venus'
            app.aEditField.Value = 6051800;       % Venus radius (m)
            app.OmegaEditField.Value = -2.99e-7;  % Default angular velocity of Venus (rad/s)
            
        case 'Mars'
            app.aEditField.Value = 3389500;
            app.OmegaEditField.Value = 7.0882e-5;

        case 'Mercury'
            app.aEditField.Value = 2439700;
            app.OmegaEditField.Value = 1.24e-6;

        case 'Jupiter'
            app.aEditField.Value = 69911000;
            app.OmegaEditField.Value = 1.76e-4;

        case 'Saturn'
            app.aEditField.Value = 58232000;
            app.OmegaEditField.Value = 1.64e-4;

        case 'Uranus'
            app.aEditField.Value = 25362000;
            app.OmegaEditField.Value = -1.01e-4;

        case 'Neptune'
            app.aEditField.Value = 24622000;
            app.OmegaEditField.Value = 1.08e-4;
    end
        end

        % Value changed function: HoldonCheckBox
        function HoldonCheckBoxValueChanged(app, event)
    if app.HoldonCheckBox.Value
        hold(app.UIAxes, 'on');  % Enable hold on
    else
        hold(app.UIAxes, 'off'); % Disable hold on
    end
        end

        % Button pushed function: ResetFigureButton
        function ResetFigureButtonPushed(app, event)
% Clear the axes without resetting the 'Hold On' state
    cla(app.UIAxes, 'reset'); % 'reset' will keep hold on the current state

    % Reset planet counter if necessary
    app.PlanetCounter = 0;

    % Ensure Hold On is active after reset
    hold(app.UIAxes, 'on');

    % Reset the dropdown items
    app.HideGraphDropDown.Items = {};

    % Clear the list of represented curves
    app.RepresentedCurves = {};
    
    % Optionally clear any stored curve visibility states
    app.CurveVisibility = containers.Map;
    
    % Re-enable the grid
    grid(app.UIAxes, 'on');
        end

        % Menu selected function: SaveplotMenu
        function SaveplotMenuSelected(app, event)
             % Open a save dialog box
            [file, path] = uiputfile({'*.png'; '*.jpg'; '*.pdf'; '*.fig'}, 'Save Plot As');
            
            % If user cancels, do nothing
            if isequal(file, 0) || isequal(path, 0)
                return;
            end
            
            % Build the full file name
            fullFileName = fullfile(path, file);
            
            % Get the file extension
            [~, ~, ext] = fileparts(fullFileName);
            
            % Save the current axes as the selected format
            switch ext
                case '.png'
                    exportgraphics(app.UIAxes, fullFileName, 'Resolution', 300);
                case '.jpg'
                    exportgraphics(app.UIAxes, fullFileName, 'Resolution', 300);
                case '.pdf'
                    exportgraphics(app.UIAxes, fullFileName);
                case '.fig'
                    % Save as a MATLAB figure
                    fig = figure('Visible', 'off'); % Create an invisible figure
                    axCopy = copyobj(app.UIAxes, fig); % Copy the axes to the new figure
                    set(axCopy, 'Units', 'Normalized', 'Position', [0.13 0.11 0.775 0.815]); % Resize
                    savefig(fig, fullFileName); % Save the figure
                    close(fig); % Close the temporary figure
                otherwise
                    uialert(app.UIFigure, 'Unsupported file format.', 'Error');
            end
        end

        % Menu selected function: ExportConditionsMenu
        function ExportConditionsMenuSelected(app, event)
            fields = {
                app.OmegaEditFieldLabel.Text, app.OmegaEditField.Value;
                app.phiEditFieldLabel.Text, app.phiEditField.Value;
                app.aEditFieldLabel.Text, app.aEditField.Value;
                app.tEditFieldLabel.Text, app.tEditField.Value;
                app.x0EditFieldLabel.Text, app.x0EditField.Value;
                app.y0EditFieldLabel.Text, app.y0EditField.Value;
                app.z0EditFieldLabel.Text, app.z0EditField.Value;
                app.u0EditFieldLabel.Text, app.u0EditField.Value;
                app.v0EditFieldLabel.Text, app.v0EditField.Value;
                app.w0EditFieldLabel.Text, app.w0EditField.Value;
            };
        
            % Exportar cada campo al workspace
            for i = 1:size(fields, 1)
                fieldName = fields{i, 1};
                fieldValue = fields{i, 2};
                assignin('base', matlab.lang.makeValidName(fieldName), fieldValue);
            end
        
            uialert(app.UIFigure, 'Configuration exported to workspace.', 'Exportation Completed')
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
            % Open a save dialog box
    [file, path] = uiputfile({'*.png'; '*.jpg'; '*.pdf'; '*.fig'}, 'Save Plot As');
    
    % If user cancels, do nothing
    if isequal(file, 0) || isequal(path, 0)
        return;
    end
    
    % Build the full file name
    fullFileName = fullfile(path, file);
    
    % Get the file extension
    [~, ~, ext] = fileparts(fullFileName);
    
    % Save the current axes as the selected format
    switch ext
        case '.png'
            exportgraphics(app.UIAxes, fullFileName, 'Resolution', 300);
        case '.jpg'
            exportgraphics(app.UIAxes, fullFileName, 'Resolution', 300);
        case '.pdf'
            exportgraphics(app.UIAxes, fullFileName);
        case '.fig'
            % Save as a MATLAB figure
            fig = figure('Visible', 'off'); % Create an invisible figure
            axCopy = copyobj(app.UIAxes, fig); % Copy the axes to the new figure
            set(axCopy, 'Units', 'Normalized', 'Position', [0.13 0.11 0.775 0.815]); % Resize
            savefig(fig, fullFileName); % Save the figure
            close(fig); % Close the temporary figure
        otherwise
            uialert(app.UIFigure, 'Unsupported file format.', 'Error');
    end
        end

        % Menu selected function: ExportDataMenu
        function ExportDataMenuSelected(app, event)
            app.exportGraphDataToWorkspace();
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
    selection = uiconfirm(app.UIFigure, ...
        'Are you sure you want to exit?', ...
        'Exit Application', ...
        'Options', {'Yes', 'No'}, ...
        'DefaultOption', 'No', ...
        'CancelOption', 'No');

    if strcmp(selection, 'Yes')
        delete(app);
    end
        end

        % Menu selected function: ExporttoWorkspaceMenu
        function ExporttoWorkspaceMenuSelected(app, event)
            % Obtener el objeto gráfico activo
            srcObject = app.UIAxes.Children; % Obtiene todos los objetos del eje
        
            % Iterar sobre los objetos del eje (por si hay varios gráficos)
            for i = 1:numel(srcObject)
                if strcmp(srcObject(i).Type, 'line') % Verificar si es una línea
                    % Extraer datos X e Y de la línea
                    xData = srcObject(i).XData;
                    yData = srcObject(i).YData;
                    
                    % Crear una matriz con dos columnas: X y Y
                    data = [xData(:), yData(:)]; % Asegurarse de que sean columnas
                    
                    % Obtener el nombre descriptivo (DisplayName)
                    graphName = srcObject(i).DisplayName;
                    
                    % Validar el nombre para que sea un nombre de variable válido
                    if isempty(graphName) % Si no hay DisplayName, usar un nombre genérico
                        graphName = sprintf('ExportedData_%d', i);
                    else
                        graphName = matlab.lang.makeValidName(graphName); % Asegurarse de que sea válido
                    end
                    
                    % Exportar al Workspace con el nombre descriptivo
                    assignin('base', graphName, data);
                end
            end
            
            % Notificación al usuario
            uialert(app.UIFigure, 'Data Exported to Workspace', 'Exportation completed');
        end

        % Menu selected function: ShowDegreesRadiansCalculatorMenu
        function ShowDegreesRadiansCalculatorMenuSelected(app, event)
            run('AngleCalculator.mlapp')
        end

        % Menu selected function: RenamecurveMenu
        function RenamecurveMenuSelected(app, event)
            % Obtener las gráficas en el UIAxes
            lines = findobj(app.UIAxes, 'Type', 'line'); % Encuentra todas las líneas en el eje
            
            % Verificar si hay líneas disponibles para renombrar
            if isempty(lines)
                uialert(app.UIFigure, 'No curves available to rename.', 'Error');
                return;
            end
            
            % Crear un cuadro de diálogo para seleccionar la gráfica a renombrar
            graphNames = get(lines, 'DisplayName'); % Obtiene los nombres actuales de las gráficas
            if ischar(graphNames) % Si solo hay una gráfica, convierte el nombre en celda
                graphNames = {graphNames};
            end
            
            [indx, tf] = listdlg('ListString', graphNames, ...
                                 'SelectionMode', 'single', ...
                                 'PromptString', 'Select a curve to rename:', ...
                                 'Name', 'Rename Curve');
            
            if ~tf % Si el usuario cancela
                return;
            end
            
            % Solicitar el nuevo nombre
            newName = inputdlg('Enter a new name for the curve:', 'Rename Curve', [1 50]);
            if isempty(newName) || isempty(newName{1}) % Si se cancela o no se ingresa un nombre
                return;
            end
            
            % Asignar el nuevo nombre a la gráfica seleccionada
            selectedLine = lines(indx); % Línea seleccionada
            selectedLine.DisplayName = newName{1};
            
            % Actualizar la leyenda (si hay una activa)
            legend(app.UIAxes, 'show');
        end

        % Menu selected function: HidecurveMenu
        function HidecurveMenuSelected(app, event)
            % Obtener las gráficas en el UIAxes
            lines = findobj(app.UIAxes, 'Type', 'line'); % Encuentra todas las líneas en el eje
            
            % Verificar si hay líneas disponibles para renombrar
            if isempty(lines)
                uialert(app.UIFigure, 'No curves available to hide.', 'Error');
                return;
            end
            
            % Crear un cuadro de diálogo para seleccionar la gráfica a renombrar
            graphNames = get(lines, 'DisplayName'); % Obtiene los nombres actuales de las gráficas
            if ischar(graphNames) % Si solo hay una gráfica, convierte el nombre en celda
                graphNames = {graphNames};
            end
            
            [indx, tf] = listdlg('ListString', graphNames, ...
                                 'SelectionMode', 'multiple', ...
                                 'PromptString', 'Select a curve to hide:', ...
                                 'Name', 'Hide Curve');
            
            if ~tf % Si el usuario cancela
                return;
            end

            % Ocultar la gráfica seleccionada
            selectedLine = lines(indx); % Línea seleccionada
            selectedLine.Visible = 'off';
            
            % Actualizar la leyenda (si hay una activa)
            legend(app.UIAxes, 'show');
        end

        % Menu selected function: ShowcurveMenu
        function ShowcurveMenuSelected(app, event)
            % Obtener las gráficas en el UIAxes
            lines = findobj(app.UIAxes, 'Type', 'line'); % Encuentra todas las líneas en el eje
            
            % Verificar si hay líneas disponibles para renombrar
            if isempty(lines)
                uialert(app.UIFigure, 'No curves available to show.', 'Error');
                return;
            end
            
            % Crear un cuadro de diálogo para seleccionar la gráfica a renombrar
            graphNames = get(lines, 'DisplayName'); % Obtiene los nombres actuales de las gráficas
            if ischar(graphNames) % Si solo hay una gráfica, convierte el nombre en celda
                graphNames = {graphNames};
            end
            
            [indx, tf] = listdlg('ListString', graphNames, ...
                                 'SelectionMode', 'multiple', ...
                                 'PromptString', 'Select a curve to show:', ...
                                 'Name', 'Show Curve');
            
            if ~tf % Si el usuario cancela
                return;
            end

            % Ocultar la gráfica seleccionada
            selectedLine = lines(indx); % Línea seleccionada
            selectedLine.Visible = 'on';
            
            % Actualizar la leyenda (si hay una activa)
            legend(app.UIAxes, 'show');
        end

        % Menu selected function: OpenHelpMenu
        function OpenHelpMenuSelected(app, event)
            run('HelpApp.mlapp')
        end

        % Menu selected function: OpenDocumentationMenu
        function OpenDocumentationMenuSelected(app, event)
            run('DocumentationApp.mlapp')
        end

        % Menu selected function: AngleCalculatorMenu
        function AngleCalculatorMenuSelected(app, event)
            run('AngleCalculator.mlapp')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveplotMenu = uimenu(app.FileMenu);
            app.SaveplotMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveplotMenuSelected, true);
            app.SaveplotMenu.Text = 'Save plot';
            app.ExportToWorkspaceMenu = uimenu(app.FileMenu);
            app.ExportToWorkspaceMenu.Text = 'Export To Workspace';
            app.ExportConditionsMenu = uimenu(app.ExportToWorkspaceMenu);
            app.ExportConditionsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportConditionsMenuSelected, true);
            app.ExportConditionsMenu.Text = 'Export Conditions';
            app.ExportDataMenu = uimenu(app.ExportToWorkspaceMenu);
            app.ExportDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportDataMenuSelected, true);
            app.ExportDataMenu.Text = 'Export Data';
            app.AngleCalculatorMenu = uimenu(app.FileMenu);
            app.AngleCalculatorMenu.MenuSelectedFcn = createCallbackFcn(app, @AngleCalculatorMenuSelected, true);
            app.AngleCalculatorMenu.Text = 'Angle Calculator';
            app.HelpMenu_2 = uimenu(app.UIFigure);
            app.HelpMenu_2.Text = 'Help';
            app.HelpMenu = uimenu(app.HelpMenu_2);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.DocumentationMenu = uimenu(app.HelpMenu_2);
            app.DocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @DocumentationMenuSelected, true);
            app.DocumentationMenu.Text = 'Documentation';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '3D Trajectory of the Body in Space')
            xlabel(app.UIAxes, 'X Position (m)')
            ylabel(app.UIAxes, 'Y Position (m)')
            zlabel(app.UIAxes, 'Z Position (m)')
            app.PlanetsAngularVelocityinradsPanel = uipanel(app.UIFigure);
            app.PlanetsAngularVelocityinradsPanel.Title = 'Planet''s Angular Velocity (in rad/s)';
            app.LatitudeinradPanel = uipanel(app.UIFigure);
            app.LatitudeinradPanel.Title = 'Latitude (in rad)';
            app.PlanetRadiusinmPanel = uipanel(app.UIFigure);
            app.PlanetRadiusinmPanel.Title = 'Planet Radius (in m)';
            app.SimulationTimeinsPanel = uipanel(app.UIFigure);
            app.SimulationTimeinsPanel.Title = 'Simulation Time (in s)';
            app.InitialConditionsinISUnitsPanel = uipanel(app.UIFigure);
            app.InitialConditionsinISUnitsPanel.Title = 'Initial Conditions (in IS Units)';
            app.u0EditFieldLabel = uilabel(app.UIFigure);
            app.u0EditFieldLabel.Text = 'u0';
            app.u0EditField = uieditfield(app.UIFigure, 'numeric');
            app.v0EditFieldLabel = uilabel(app.UIFigure);
            app.v0EditFieldLabel.Text = 'v0';
            app.v0EditField = uieditfield(app.UIFigure, 'numeric');
            app.w0EditFieldLabel = uilabel(app.UIFigure);
            app.w0EditFieldLabel.Text = 'w0';
            app.w0EditField = uieditfield(app.UIFigure, 'numeric');
            app.x0EditFieldLabel = uilabel(app.UIFigure);
            app.x0EditFieldLabel.Text = 'x0';
            app.x0EditField = uieditfield(app.UIFigure, 'numeric');
            app.y0EditFieldLabel = uilabel(app.UIFigure);
            app.y0EditFieldLabel.Text = 'y0';
            app.y0EditField = uieditfield(app.UIFigure, 'numeric');
            app.z0EditFieldLabel = uilabel(app.UIFigure);
            app.z0EditFieldLabel.Text = 'z0';
            app.z0EditField = uieditfield(app.UIFigure, 'numeric');
            app.RepresenttrajectoryButton = uibutton(app.UIFigure, 'push');
            app.RepresenttrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @RepresenttrajectoryButtonPushed, true);
            app.RepresenttrajectoryButton.Text = 'Represent trajectory';
            app.tEditFieldLabel = uilabel(app.UIFigure);
            app.tEditFieldLabel.Text = 't';
            app.tEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.phiEditFieldLabel = uilabel(app.UIFigure);
            app.phiEditFieldLabel.Text = 'phi';
            app.phiEditField = uieditfield(app.UIFigure, 'numeric');
            app.OmegaEditFieldLabel = uilabel(app.UIFigure);
            app.OmegaEditFieldLabel.Text = 'Omega';
            app.OmegaEditField = uieditfield(app.UIFigure, 'numeric');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'US-marca-principal.png');
            app.CommonplanetsDropDownLabel = uilabel(app.UIFigure);
            app.CommonplanetsDropDownLabel.Text = 'Common planets';
            app.CommonplanetsDropDown = uidropdown(app.UIFigure);
            app.CommonplanetsDropDown.Items = {'Mercury', 'Venus', 'Earth', 'Mars', 'Jupiter', 'Saturn', 'Uranus', 'Neptune'};
            app.CommonplanetsDropDown.ValueChangedFcn = createCallbackFcn(app, @CommonplanetsDropDownValueChanged, true);
            app.CommonplanetsDropDown.Value = 'Mercury';
            app.HoldonCheckBox = uicheckbox(app.UIFigure);
            app.HoldonCheckBox.ValueChangedFcn = createCallbackFcn(app, @HoldonCheckBoxValueChanged, true);
            app.HoldonCheckBox.Text = 'Hold on';
            app.ResetFigureButton = uibutton(app.UIFigure, 'push');
            app.ResetFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFigureButtonPushed, true);
            app.ResetFigureButton.Text = 'Reset Figure';
            app.MOMENTUMEQUATIONSINASTROPHYSICSLabel = uilabel(app.UIFigure);
            app.MOMENTUMEQUATIONSINASTROPHYSICSLabel.Text = 'MOMENTUM EQUATIONS IN ASTROPHYSICS';
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.SaveasMenu = uimenu(app.ContextMenu2);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Text = 'Save as';
            app.ExporttoWorkspaceMenu = uimenu(app.ContextMenu2);
            app.ExporttoWorkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttoWorkspaceMenuSelected, true);
            app.ExporttoWorkspaceMenu.Text = 'Export to Workspace';
            app.RenamecurveMenu = uimenu(app.ContextMenu2);
            app.RenamecurveMenu.MenuSelectedFcn = createCallbackFcn(app, @RenamecurveMenuSelected, true);
            app.RenamecurveMenu.Text = 'Rename curve';
            app.HidecurveMenu = uimenu(app.ContextMenu2);
            app.HidecurveMenu.MenuSelectedFcn = createCallbackFcn(app, @HidecurveMenuSelected, true);
            app.HidecurveMenu.Text = 'Hide curve';
            app.ShowcurveMenu = uimenu(app.ContextMenu2);
            app.ShowcurveMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowcurveMenuSelected, true);
            app.ShowcurveMenu.Text = 'Show curve';
            % Assign app.ContextMenu2
            app.UIAxes.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.UIFigure);
            app.OpenHelpMenu = uimenu(app.ContextMenu3);
            app.OpenHelpMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenHelpMenuSelected, true);
            app.OpenHelpMenu.Text = 'Open Help';
            app.OpenDocumentationMenu = uimenu(app.ContextMenu3);
            app.OpenDocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenDocumentationMenuSelected, true);
            app.OpenDocumentationMenu.Text = 'Open Documentation';
            app.ExitMenu = uimenu(app.ContextMenu3);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            % Assign app.ContextMenu3
            app.UIFigure.ContextMenu = app.ContextMenu3;
            app.ContextMenu4 = uicontextmenu(app.UIFigure);
            app.ShowDegreesRadiansCalculatorMenu = uimenu(app.ContextMenu4);
            app.ShowDegreesRadiansCalculatorMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowDegreesRadiansCalculatorMenuSelected, true);
            app.ShowDegreesRadiansCalculatorMenu.Text = 'Show Degrees-Radians Calculator';
            % Assign app.ContextMenu4
            app.phiEditField.ContextMenu = app.ContextMenu4;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MomentumEquationsAstroApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end